classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ToolWearEditField          matlab.ui.control.NumericEditField
        ToolWearEditFieldLabel     matlab.ui.control.Label
        TorqueEditField            matlab.ui.control.NumericEditField
        TorqueEditFieldLabel       matlab.ui.control.Label
        RPMEditField               matlab.ui.control.NumericEditField
        RPMEditFieldLabel          matlab.ui.control.Label
        ProcessTempEditField       matlab.ui.control.NumericEditField
        ProcessTempEditFieldLabel  matlab.ui.control.Label
        AirTempEditField           matlab.ui.control.NumericEditField
        AirTempEditFieldLabel      matlab.ui.control.Label
        ResultLabel                matlab.ui.control.Label
        PredictButton              matlab.ui.control.Button
        ToolwearminLabel           matlab.ui.control.Label
        TorqueNmLabel              matlab.ui.control.Label
        RPMLabel                   matlab.ui.control.Label
        processtempKLabel          matlab.ui.control.Label
        AirtempKLabel              matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
% Load trained model
modelData = load('trained_model.mat');
model = modelData.Mdl;

% Get user inputs
airTemp = app.AirTempEditField.Value;
processTemp = app.ProcessTempEditField.Value;
rpm = app.RPMEditField.Value;
torque = app.TorqueEditField.Value;
toolWear = app.ToolWearEditField.Value;

% Combine into feature vector
features = [toolWear, torque, airTemp, processTemp, rpm];

% Predict with probability score
[prediction, scores] = predict(model, features);
confidence = max(scores) * 100;

% Show prediction in GUI
if prediction == 0
    app.ResultLabel.Text = sprintf("✅ Machine is Healthy.\nConfidence: %.2f%%", confidence);
    app.ResultLabel.FontColor = [0 0.8 0];
else 
    app.ResultLabel.Text = sprintf("⚠️ Machine Failure Predicted!\nConfidence: %.2f%%", confidence);
    app.ResultLabel.FontColor = [1 0 0];
end

% Plot Torque vs RPM with color
cla(app.UIAxes);
scatterColor = [0 0.8 0];
if prediction == 1
    scatterColor = [1 0 0];
end
scatter(app.UIAxes, rpm, torque, 100, scatterColor, 'filled');
title(app.UIAxes, 'Torque vs RPM');
xlabel(app.UIAxes, 'RPM');
ylabel(app.UIAxes, 'Torque (Nm)');
grid(app.UIAxes, 'on');

% Timestamp
timestamp = datestr(now, 'yyyy-mm-dd HH:MM:SS');

% Report text with confidence
reportText = sprintf([ ...
    'Predictive Maintenance Report\n', ...
    '-----------------------------\n', ...
    'Date: %s\n\n', ...
    'Input Values:\n', ...
    '- Tool Wear (min): %.2f\n', ...
    '- Torque (Nm): %.2f\n', ...
    '- Air Temperature (K): %.2f\n', ...
    '- Process Temperature (K): %.2f\n', ...
    '- RPM: %.2f\n\n', ...
    'Prediction Result:\n', ...
    '%s (Confidence: %.2f%%)\n\n', ...
    'Generated by: Induction Motor Health Predictor App (MATLAB)\n'], ...
    timestamp, toolWear, torque, airTemp, processTemp, rpm, ...
    erase(app.ResultLabel.Text, '\n'), confidence);

% Save report
fid = fopen('Prediction_Report.txt', 'w');
fprintf(fid, '%s', reportText);
fclose(fid);

% Confirm to user
uialert(app.UIFigure, 'Prediction report saved as Prediction_Report.txt', 'Report Generated');


        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'UI Axes2')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AirtempKLabel = uilabel(app.UIFigure);
            app.AirtempKLabel.Text = 'Air temp(K)';
            app.processtempKLabel = uilabel(app.UIFigure);
            app.processtempKLabel.Text = 'process temp(K)';
            app.RPMLabel = uilabel(app.UIFigure);
            app.RPMLabel.Text = 'RPM';
            app.TorqueNmLabel = uilabel(app.UIFigure);
            app.TorqueNmLabel.Text = 'Torque(Nm)';
            app.ToolwearminLabel = uilabel(app.UIFigure);
            app.ToolwearminLabel.Text = 'Tool wear(min)';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict ';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'ResultLabel';
            app.AirTempEditFieldLabel = uilabel(app.UIFigure);
            app.AirTempEditFieldLabel.Text = 'AirTempEdit Field';
            app.AirTempEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProcessTempEditFieldLabel = uilabel(app.UIFigure);
            app.ProcessTempEditFieldLabel.Text = 'ProcessTempEdit Field';
            app.ProcessTempEditField = uieditfield(app.UIFigure, 'numeric');
            app.RPMEditFieldLabel = uilabel(app.UIFigure);
            app.RPMEditFieldLabel.Text = 'RPMEdit Field';
            app.RPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.TorqueEditFieldLabel = uilabel(app.UIFigure);
            app.TorqueEditFieldLabel.Text = 'TorqueEdit Field';
            app.TorqueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToolWearEditFieldLabel = uilabel(app.UIFigure);
            app.ToolWearEditFieldLabel.Text = 'ToolWearEdit Field';
            app.ToolWearEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end